classdef compGUIv2_ElectrodeGrid < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        push_remchan             matlab.ui.control.Button
        inp_gridmax              matlab.ui.control.NumericEditField
        MaxEditFieldLabel        matlab.ui.control.Label
        inp_gridmin              matlab.ui.control.NumericEditField
        MinEditFieldLabel        matlab.ui.control.Label
        inp_griddur              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel  matlab.ui.control.Label
        pop_elecgrid             matlab.ui.control.DropDown
        ShowDropDownLabel        matlab.ui.control.Label
        ax_elecgrid              matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        gridDurationChanged % duration of electrode grid has been changed
    end

    properties (Access = private)
        gridChannelList = {}; % cell array of channel names that appear in the grid
        gridChannelX = []; % horizontal coordinates corresponding to channel list
        gridChannelY = []; % vertical coordinate corresponding to channel list
    end
    
    properties (Access = public)
        FilterSetUp logical = false; % flag that determines if filter has been set up
        elecGridFunc = @(~,~) nan; % function takes in data and sample rate and outputs grid value
        elecGridImg = []; % imagesc handle for the grid
        hicutoff double = nan; % filter high cutoff frequency
        inp_griddur_Value double = .1; % value property of inp_griddur
        locutoff double = nan; % low cutoff frequency of filter
        showElecGrid logical = false; % enable/disable this component
    end
    
    
    methods (Access = public)
        
        function initGrid(comp, channelList)
            % should be called on connection with hardware 
            gridmaxval = comp.inp_gridmax.Value;
            gridminval = comp.inp_gridmin.Value;
            axes(comp.ax_elecgrid)
            ncol = 3; % # of columns
            nrow = 21;
            img = nan(nrow, ncol);
            [X,Y] = meshgrid(1:ncol, 1:nrow);
            comp.elecGridImg = imagesc(comp.ax_elecgrid, img, [gridminval, gridmaxval]);
            colormap(comp.ax_elecgrid, 'parula'); colorbar(comp.ax_elecgrid); 
            hold(comp.ax_elecgrid,"on");
            chL_ = channelList(1:min((ncol*nrow), length(channelList)));
            X = X(:); X = X(1:length(chL_));
            Y = Y(:); Y = Y(1:length(chL_));
            text(comp.ax_elecgrid, X,Y, chL_, ...
                'HorizontalAlignment','center', ...
                'VerticalAlignment','middle', ...
                'FontWeight', 'bold', ...
                'Color',[.8 0 0]);
            % do these actually change the private property value?
            comp.gridChannelList = chL_; comp.gridChannelX = X; comp.gridChannelY = Y;
        end
        
        function updateGrid(comp, channelIDlist, rawIDs, rawD4, ...
                bufferSizeGrid, fSamples)
            % should be called in main loop whenever there is new data 
            elecimg = comp.elecGridImg.CData;
            for ch = 1:numel(elecimg)
                chID = channelIDlist(ch); 
                xInd = find(rawIDs == chID); 
                if numel(xInd)
                    x = rawD4{xInd}(:,2); 
                    if length(bufferSizeGrid) > 1
                        L = bufferSizeGrid(xInd);
                    else
                        L = bufferSizeGrid;
                    end
                    if height(x) > L
                        x = x((end-L+1):end, :);
                    end
                    if height(x) < L
                        warning(['Channel ',num2str(ch),...
                            ' / ID ',num2str(chID),...
                            ' Electrode Grid buffer is not full length!'])
                    end
                    fSample_ch = fSamples(xInd);
                    elecimg(ch) = comp.elecGridFunc(x, fSample_ch);
                else
                    elecimg(ch) = nan;
                end
            end
            comp.elecGridImg.CData = elecimg;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: pop_elecgrid
        function pop_elecgridValueChanged(comp, event)
            % changes what calculation is displayed as grid values 
            sel = comp.pop_elecgrid.Value;
            comp.showElecGrid = ~strcmp(sel, 'None');
            if comp.showElecGrid
                if strcmp(sel, 'PAC')
                    % ***** TO DO: phase amplitude coupling: handles.elecGridFunc = ...
                else
                    % __ band power
                    if strcmp(sel, 'Selected Band Power')
                        if ~comp.FilterSetUp
                            error('Filter must be set for this selection.')
                        end
                        fbnd = [comp.locutoff, comp.hicutoff];
                    elseif strcmp(sel, 'Beta Power')
                        fbnd = [13, 30]; % Hz
                    elseif strcmp(sel, 'Theta Power')
                        fbnd = [4, 9]; % Hz
                    elseif strcmp(sel, 'Gamma Power')
                        fbnd = [50, 200]; % Hz
                    end
                    comp.elecGridFunc = @(data, fs) bandpower(data, fs, fbnd);
                end
            end
        end

        % Value changed function: inp_gridmin
        function inp_gridminValueChanged(comp, event)
            value = comp.inp_gridmin.Value;
            comp.ax_elecgrid.CLim(1) = value;
        end

        % Value changed function: inp_gridmax
        function inp_gridmaxValueChanged(comp, event)
            value = comp.inp_gridmax.Value;
            comp.ax_elecgrid.CLim(2) = value;
        end

        % Value changed function: inp_griddur
        function inp_griddurValueChanged(comp, event)
            comp.inp_griddur_Value = comp.inp_griddur.Value;
            notify(comp, 'gridDurationChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 450 720];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ax_elecgrid = uiaxes(comp);
            comp.ax_elecgrid.XTick = [];
            comp.ax_elecgrid.YTick = [];
            comp.ShowDropDownLabel = uilabel(comp);
            comp.ShowDropDownLabel.Text = 'Show:';
            comp.pop_elecgrid = uidropdown(comp);
            comp.pop_elecgrid.Items = {'None', 'Selected Band Power', 'Beta Power', 'Theta Power', 'Gamma Power', 'PAC'};
            comp.pop_elecgrid.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @pop_elecgridValueChanged, true);
            comp.pop_elecgrid.Value = 'None';
            comp.DurationsEditFieldLabel = uilabel(comp);
            comp.DurationsEditFieldLabel.Text = 'Duration (s)';
            comp.inp_griddur = uieditfield(comp, 'numeric');
            comp.inp_griddur.Limits = [0 Inf];
            comp.inp_griddur.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_griddurValueChanged, true);
            comp.inp_griddur.Value = 0.1;
            comp.MinEditFieldLabel = uilabel(comp);
            comp.MinEditFieldLabel.Text = 'Min';
            comp.inp_gridmin = uieditfield(comp, 'numeric');
            comp.inp_gridmin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_gridminValueChanged, true);
            comp.MaxEditFieldLabel = uilabel(comp);
            comp.MaxEditFieldLabel.Text = 'Max';
            comp.inp_gridmax = uieditfield(comp, 'numeric');
            comp.inp_gridmax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_gridmaxValueChanged, true);
            comp.inp_gridmax.Value = 1000000;
            comp.push_remchan = uibutton(comp, 'push');
            comp.push_remchan.Text = 'Remove Channels';
        end
    end
end